classdef polya_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PolyaFilterUIFigure          matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        NoiseTab                     matlab.ui.container.Tab
        TypeDropDownLabel            matlab.ui.control.Label
        TypeDropDown                 matlab.ui.control.DropDown
        SigmaSliderLabel             matlab.ui.control.Label
        SigmaSlider                  matlab.ui.control.Slider
        ErrorSliderLabel             matlab.ui.control.Label
        ErrorSlider                  matlab.ui.control.Slider
        BurstSigmaSliderLabel        matlab.ui.control.Label
        BurstSigmaSlider             matlab.ui.control.Slider
        ConfidenceIntervalLabel      matlab.ui.control.Label
        ConfidenceIntervalSlider     matlab.ui.control.Slider
        TransitionSliderLabel        matlab.ui.control.Label
        TransitionSlider             matlab.ui.control.Slider
        EdgeTab                      matlab.ui.container.Tab
        UseEdgeMapCheckBox           matlab.ui.control.CheckBox
        SigmaSlider_2Label           matlab.ui.control.Label
        EdgeMapSigma                 matlab.ui.control.Slider
        ThresholdSliderLabel         matlab.ui.control.Label
        EdgeMapThreshold             matlab.ui.control.Slider
        QuantizationTab              matlab.ui.container.Tab
        QuantizationCheckBox         matlab.ui.control.CheckBox
        QuantizationTypeButtonGroup  matlab.ui.container.ButtonGroup
        unifButton                   matlab.ui.control.RadioButton
        lloydButton                  matlab.ui.control.RadioButton
        ofpartitionsballtypesLabel   matlab.ui.control.Label
        BallTypesSlider              matlab.ui.control.Slider
        InverseQuantizationTypeButtonGroup  matlab.ui.container.ButtonGroup
        LowButton                    matlab.ui.control.RadioButton
        MeanButton                   matlab.ui.control.RadioButton
        HighButton                   matlab.ui.control.RadioButton
        PolyaTab                     matlab.ui.container.Tab
        IterationsEditFieldLabel     matlab.ui.control.Label
        IterationsEditField          matlab.ui.control.NumericEditField
        StartingBallsSliderLabel     matlab.ui.control.Label
        StartingBallsSlider          matlab.ui.control.Slider
        BallstoAddEditFieldLabel     matlab.ui.control.Label
        BallstoAddEditField          matlab.ui.control.NumericEditField
        RadiusButtonGroup            matlab.ui.container.ButtonGroup
        Button                       matlab.ui.control.RadioButton
        Button_2                     matlab.ui.control.RadioButton
        RadiusSliderLabel            matlab.ui.control.Label
        RadiusSlider                 matlab.ui.control.Slider
        NormButtonGroup              matlab.ui.container.ButtonGroup
        Button_3                     matlab.ui.control.RadioButton
        Button_4                     matlab.ui.control.RadioButton
        InfButton                    matlab.ui.control.RadioButton
        SelectImageButton            matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        ImagePathEditFieldLabel      matlab.ui.control.Label
        ImagePathEditField           matlab.ui.control.EditField
        LoadImageButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_5                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        prefs % prefs object for polya
        noise % noise pref object for polya
        image
    end

    methods (Access = public)
    
        function results = fill_image_to_figure(app, fig, image)
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};

            I = imshow(path, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end
        
    end


    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            file = uigetfile({'*.jpg;*.tif;*.png;*.gif;*.tiff;*.bmp','All Image Files'; ...
                '*.*','All Files' });
            app.PolyaFilterUIFigure.Visible = 'off';
            app.PolyaFilterUIFigure.Visible = 'on';
            app.ImagePathEditField.Value = ['../images/' file];
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            path = app.ImagePathEditField.Value;
            
            app.image = imread(path);
            
%             https://www.mathworks.com/matlabcentral/answers/360670-imshow-in-app-designer-image-size-doesn-t-fit?requestedDomain=true
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};

            I = imshow(path, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Preferences
            imagepath = app.ImagePathEditField.Value;
            im_info = imfinfo(imagepath);
            switch(im_info.ColorType)
                case('truecolor')
                    app.prefs.image.type = 'rgb';
                case('grayscale')
                    app.prefs.image.type = 'gray';
                case('indexed')
                    app.prefs.image.type = 'bw';
                otherwise
                    disp("Can't detect image type. Need to override prefs.image.type");
            end
            % prefs.image.type = 'rgb'; % 'bw', 'gray', 'rgb', 'ycbcr'
            
            % Lena threshold: 0.52
            % oil_spill threshold: 0.18
            app.prefs.image.binarize_thresh = 0.52;
            
            app.prefs.edges.use_edges = app.UseEdgeMapCheckBox.Value;
            app.prefs.edges.filter = 'canny';
            % The variance of Canny's gaussian filter. Default is sqrt(2)
            app.prefs.edges.sigma = app.EdgeMapSigma.Value;
            % Threshold for the Canny method
            % Oil Spill: 0.4
            % Lena: with gauss = 0.2, with burst = 0.38
            % Two ships: 0.5
            app.prefs.edges.thresh = app.EdgeMapThreshold.Value;
            
            app.prefs.quant.num_ball_types = app.BallTypesSlider.Value; % [2 - 256]
%             --- the two prefs below are handled in their respective radio button callback functions
%             app.prefs.quant.type = app.; % unif, lloyd
%             app.prefs.quant.inverse = 'mid'; % low, high, mid
            
            app.prefs.adj.radius = app.RadiusSlider.Value;
%             app.prefs.adj.norm = ;
            
            app.prefs.polya.sample_type = 'median'; % 'median', 'random'
            app.prefs.polya.starting_balls = app.StartingBallsSlider.Value; % Starting balls in each urn
            % Number of balls to add to the urn after each polya step
            app.prefs.polya.balls_to_add = app.BallstoAddEditField.Value;
            app.prefs.polya.iterations = app.IterationsEditField.Value;
            
            app.prefs.median.iterations = 8;
            
            app.prefs.lee.kernel = [5 5]; % window size for lee filter
            app.prefs.lee.iterations = 8;
            
            % Noise parameters
            % BW Gaussian Noise Parameters
            app.noise.bw.gaussian_std_dev = 1;
            app.noise.bw.gaussian_mean = 0;
            app.noise.bw.gaussian_confidence_interval = 0.8; % Error rate is 1 - this
            
            % Colour & Greyscale Noise Parameters
            app.noise.gaussian.sigma = app.SigmaSlider.Value;
            app.noise.gaussian.mean = 0;
            
            app.noise.bursty.type = 'binary'; % 'gaussian' or 'binary'
            app.noise.bursty.transition_prob = app.TransitionSlider.Value;
            app.noise.bursty.error = app.ErrorSlider.Value; % 0.2 for gaussian, 0.1 for binary
            app.noise.bursty.mean = 0;
            app.noise.bursty.sigma = app.BurstSigmaSlider.Value;
            
            app.noise.gauss_markov.correlation = 0.9; % (-1, 1)
            app.noise.gauss_markov.mean = 0;
            app.noise.gauss_markov.sigma = 10;
            
%             app.noise.type = 'gauss-markov'; % 'gaussian' or 'burst' or 'both' or 'none'
            % or 'gauss-markov'
            
            %% Add Gaussian or Bursty Noise
            rng(0, 'twister');
            
            if strcmp(app.prefs.image.type, 'bw')
                % If it's an rgb image, convert it to grayscale
                if size(size(app.image), 2) > 2
                    app.image = rgb2gray(app.image);
                end
                app.image = imbinarize(app.image, app.prefs.image.binarize_thresh);
            end
            
            noisy_image = add_noise(app.image, app.prefs, app.noise);
            %             https://www.mathworks.com/matlabcentral/answers/360670-imshow-in-app-designer-image-size-doesn-t-fit?requestedDomain=true
            % Remove title, axis labels, and tick labels
            title(app.UIAxes_2, []);
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};

            I = imshow(noisy_image, 'Parent', app.UIAxes_2, ...
                'XData', [1 app.UIAxes_2.Position(3)], ...
                'YData', [1 app.UIAxes_2.Position(4)]);
            
            % Set limits of axes
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];
            
        end

        % Selection changed function: QuantizationTypeButtonGroup
        function QuantizationTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.QuantizationTypeButtonGroup.SelectedObject;
            app.prefs.quant.type = selectedButton;
        end

        % Selection changed function: 
        % InverseQuantizationTypeButtonGroup
        function InverseQuantizationTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InverseQuantizationTypeButtonGroup.SelectedObject;
            app.prefs.quant.inverse = selectedButton;
        end

        % Selection changed function: NormButtonGroup
        function NormButtonGroupSelectionChanged(app, event)
            selectedButton = app.NormButtonGroup.SelectedObject;
            app.prefs.adj.norm = selectedButton;
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            app.noise.type = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PolyaFilterUIFigure = uifigure;
            app.PolyaFilterUIFigure.Name = 'Polya Filter';
            app.TabGroup = uitabgroup(app.PolyaFilterUIFigure);
            app.NoiseTab = uitab(app.TabGroup);
            app.NoiseTab.Title = 'Noise';
            app.TypeDropDownLabel = uilabel(app.NoiseTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.NoiseTab);
            app.TypeDropDown.Items = {'None', 'Gaussian', 'Binary Burst', 'Gaussian Markov', 'Gaussian Burst (Hidden Markov)'};
            app.TypeDropDown.ItemsData = {'none', 'gaussian', 'burst', 'both', 'both', 'gauss-markov'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'none';
            app.SigmaSliderLabel = uilabel(app.NoiseTab);
            app.SigmaSliderLabel.Text = 'Sigma';
            app.SigmaSlider = uislider(app.NoiseTab);
            app.ErrorSliderLabel = uilabel(app.NoiseTab);
            app.ErrorSliderLabel.Text = 'Error';
            app.ErrorSlider = uislider(app.NoiseTab);
            app.ErrorSlider.Limits = [0 1];
            app.BurstSigmaSliderLabel = uilabel(app.NoiseTab);
            app.BurstSigmaSliderLabel.Text = 'Burst Sigma';
            app.BurstSigmaSlider = uislider(app.NoiseTab);
            app.ConfidenceIntervalLabel = uilabel(app.NoiseTab);
            app.ConfidenceIntervalLabel.Text = {'Confidence '; 'Interval'};
            app.ConfidenceIntervalSlider = uislider(app.NoiseTab);
            app.TransitionSliderLabel = uilabel(app.NoiseTab);
            app.TransitionSliderLabel.Text = 'Transition';
            app.TransitionSlider = uislider(app.NoiseTab);
            app.TransitionSlider.Limits = [0 1];
            app.EdgeTab = uitab(app.TabGroup);
            app.EdgeTab.Title = 'Edge';
            app.UseEdgeMapCheckBox = uicheckbox(app.EdgeTab);
            app.UseEdgeMapCheckBox.Text = 'Use Edge Map';
            app.SigmaSlider_2Label = uilabel(app.EdgeTab);
            app.SigmaSlider_2Label.Text = 'Sigma';
            app.EdgeMapSigma = uislider(app.EdgeTab);
            app.EdgeMapSigma.Limits = [0 5];
            app.ThresholdSliderLabel = uilabel(app.EdgeTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.EdgeMapThreshold = uislider(app.EdgeTab);
            app.QuantizationTab = uitab(app.TabGroup);
            app.QuantizationTab.Title = 'Quantization';
            app.QuantizationCheckBox = uicheckbox(app.QuantizationTab);
            app.QuantizationCheckBox.Text = 'Quantization';
            app.QuantizationTypeButtonGroup = uibuttongroup(app.QuantizationTab);
            app.QuantizationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @QuantizationTypeButtonGroupSelectionChanged, true);
            app.QuantizationTypeButtonGroup.Title = 'Quantization Type';
            app.unifButton = uiradiobutton(app.QuantizationTypeButtonGroup);
            app.unifButton.Text = 'unif';
            app.unifButton.Value = true;
            app.lloydButton = uiradiobutton(app.QuantizationTypeButtonGroup);
            app.lloydButton.Text = 'lloyd';
            app.ofpartitionsballtypesLabel = uilabel(app.QuantizationTab);
            app.ofpartitionsballtypesLabel.Text = {'# of partitions '; '/ ball types'};
            app.BallTypesSlider = uislider(app.QuantizationTab);
            app.BallTypesSlider.Limits = [2 256];
            app.BallTypesSlider.Value = 20;
            app.InverseQuantizationTypeButtonGroup = uibuttongroup(app.QuantizationTab);
            app.InverseQuantizationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InverseQuantizationTypeButtonGroupSelectionChanged, true);
            app.InverseQuantizationTypeButtonGroup.Title = 'Inverse Quantization Type';
            app.LowButton = uiradiobutton(app.InverseQuantizationTypeButtonGroup);
            app.LowButton.Text = 'Low';
            app.LowButton.Value = true;
            app.MeanButton = uiradiobutton(app.InverseQuantizationTypeButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.HighButton = uiradiobutton(app.InverseQuantizationTypeButtonGroup);
            app.HighButton.Text = 'High';
            app.PolyaTab = uitab(app.TabGroup);
            app.PolyaTab.Title = 'Polya';
            app.IterationsEditFieldLabel = uilabel(app.PolyaTab);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.PolyaTab, 'numeric');
            app.IterationsEditField.Limits = [0 Inf];
            app.IterationsEditField.RoundFractionalValues = 'on';
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.StartingBallsSliderLabel = uilabel(app.PolyaTab);
            app.StartingBallsSliderLabel.Text = 'Starting Balls';
            app.StartingBallsSlider = uislider(app.PolyaTab);
            app.StartingBallsSlider.Limits = [100 1000];
            app.StartingBallsSlider.Value = 100;
            app.BallstoAddEditFieldLabel = uilabel(app.PolyaTab);
            app.BallstoAddEditFieldLabel.Text = 'Balls to Add';
            app.BallstoAddEditField = uieditfield(app.PolyaTab, 'numeric');
            app.BallstoAddEditField.Limits = [0 Inf];
            app.BallstoAddEditField.RoundFractionalValues = 'on';
            app.BallstoAddEditField.ValueDisplayFormat = '%.0f';
            app.RadiusButtonGroup = uibuttongroup(app.PolyaTab);
            app.RadiusButtonGroup.Title = 'Radius';
            app.Button = uiradiobutton(app.RadiusButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.RadiusButtonGroup);
            app.Button_2.Text = '2';
            app.RadiusSliderLabel = uilabel(app.PolyaTab);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.PolyaTab);
            app.RadiusSlider.Limits = [1 10];
            app.RadiusSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.RadiusSlider.Value = 1;
            app.NormButtonGroup = uibuttongroup(app.PolyaTab);
            app.NormButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormButtonGroupSelectionChanged, true);
            app.NormButtonGroup.Title = 'Norm';
            app.Button_3 = uiradiobutton(app.NormButtonGroup);
            app.Button_3.Text = '1';
            app.Button_3.Value = true;
            app.Button_4 = uiradiobutton(app.NormButtonGroup);
            app.Button_4.Text = '2';
            app.InfButton = uiradiobutton(app.NormButtonGroup);
            app.InfButton.Text = 'Inf';
            app.SelectImageButton = uibutton(app.PolyaFilterUIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.RunButton = uibutton(app.PolyaFilterUIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ImagePathEditFieldLabel = uilabel(app.PolyaFilterUIFigure);
            app.ImagePathEditFieldLabel.Text = 'Image Path';
            app.ImagePathEditField = uieditfield(app.PolyaFilterUIFigure, 'text');
            app.ImagePathEditField.Editable = 'off';
            app.LoadImageButton = uibutton(app.PolyaFilterUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.UIAxes = uiaxes(app.PolyaFilterUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.PolyaFilterUIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_4 = uiaxes(app.PolyaFilterUIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.UIAxes_5 = uiaxes(app.PolyaFilterUIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = polya_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PolyaFilterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PolyaFilterUIFigure)
        end
    end
end